classdef test_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SMWBBControlTab                 matlab.ui.container.Tab
        AlignButton                     matlab.ui.control.Button
        AlignmentFilenameEditField      matlab.ui.control.EditField
        AlignmentFilenameEditFieldLabel  matlab.ui.control.Label
        RFPortAlignmentLabel            matlab.ui.control.Label
        SubnetEditField0                matlab.ui.control.NumericEditField
        BBSubnetLabel                   matlab.ui.control.Label
        SMWIPAddressEditField           matlab.ui.control.EditField
        SMWIPAddressEditFieldLabel      matlab.ui.control.Label
        BasebandConfigLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SubnetEditField2                matlab.ui.control.NumericEditField
        SubnetEditField1                matlab.ui.control.NumericEditField
        BB_Table                        matlab.ui.control.Table
        InitBasebandsButton             matlab.ui.control.Button
        MessageLabel                    matlab.ui.control.Label
        Connect_Message                 matlab.ui.control.Label
        SMWControlInterfaceLabel        matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        ExectutePDWsTab                 matlab.ui.container.Tab
        StreamButton_2                  matlab.ui.control.Button
        AddRowButton                    matlab.ui.control.Button
        PDWTable                        matlab.ui.control.Table
        StatusLabel                     matlab.ui.control.Label
        PDWFilenameEditField            matlab.ui.control.EditField
        PDWFilenameEditFieldLabel       matlab.ui.control.Label
        StreamButton                    matlab.ui.control.Button
        RealTimeStreamPDWsLabel         matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
        StreamExcelFileLabel            matlab.ui.control.Label
        EmitterInterfaceTab             matlab.ui.container.Tab
        BasebandDropDown                matlab.ui.control.DropDown
        BasebandDropDownLabel           matlab.ui.control.Label
        RemoveEmitterButton_4           matlab.ui.control.Button
        AddEmitterButton                matlab.ui.control.Button
        SendButton_3                    matlab.ui.control.Button
        EmitterTable                    matlab.ui.control.Table
        PhaseFilenameEditField          matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        SendButton_2                    matlab.ui.control.Button
        SendButton                      matlab.ui.control.Button
        AmpFilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ConnectButton_2                 matlab.ui.control.Button
        IPAddressEditField              matlab.ui.control.EditField
        IPAddressEditFieldLabel         matlab.ui.control.Label
        EmitterStreamLabel              matlab.ui.control.Label
        AntennaPhaseFileStreamLabel     matlab.ui.control.Label
        AntennaAmplitudeFileStreamLabel  matlab.ui.control.Label
        ConnectStreamingEngineLabel     matlab.ui.control.Label
        AIMLModelTab                    matlab.ui.container.Tab
        ClearTrainingFolderButton       matlab.ui.control.Button
        NewSignalNameEditField          matlab.ui.control.EditField
        NewSignalNameEditFieldLabel     matlab.ui.control.Label
        CollectTrainingFramesPDWEngineButton  matlab.ui.control.Button
        PredictionStopButton            matlab.ui.control.Button
        ConfidenceValueEditField        matlab.ui.control.NumericEditField
        ConfidenceValueEditFieldLabel   matlab.ui.control.Label
        PredictedSignalIdxEditField     matlab.ui.control.NumericEditField
        PredictedSignalIdxEditFieldLabel  matlab.ui.control.Label
        PredictionActiveButton          matlab.ui.control.Button
        TrainingPathButton_2            matlab.ui.control.Button
        DeployNetworkButton             matlab.ui.control.Button
        DeployNetworkEditField          matlab.ui.control.EditField
        DeployNetworkEditFieldLabel     matlab.ui.control.Label
        NewNetworkNameEditField         matlab.ui.control.EditField
        NewNetworkNameEditFieldLabel    matlab.ui.control.Label
        CollectTrainingFramesArbFilesButton  matlab.ui.control.Button
        AddSignalButton                 matlab.ui.control.Button
        SignalFileTable                 matlab.ui.control.Table
        TrainNewNetworkButton           matlab.ui.control.Button
        TrainingPathButton              matlab.ui.control.Button
        TrainingFolderEditField         matlab.ui.control.EditField
        TrainingFolderEditFieldLabel    matlab.ui.control.Label
        ConnectButton_4                 matlab.ui.control.Button
        ConnectButton_3                 matlab.ui.control.Button
        FSWIPAddressEditField           matlab.ui.control.EditField
        FSWIPAddressLabel               matlab.ui.control.Label
        SMWIPAddressEditField_2         matlab.ui.control.EditField
        SMWIPAddressLabel               matlab.ui.control.Label
        ADWStreamingTab                 matlab.ui.container.Tab
        infiniteLabel                   matlab.ui.control.Label
        usLabel                         matlab.ui.control.Label
        DegreesLabel                    matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        BUSRTADDSEGMENTSEditField       matlab.ui.control.NumericEditField
        BUSRTADDSEGMENTSEditFieldLabel  matlab.ui.control.Label
        BUSRTSRIEditField               matlab.ui.control.NumericEditField
        BUSRTSRIEditFieldLabel          matlab.ui.control.Label
        SEGMENTIDXEditField             matlab.ui.control.NumericEditField
        SEGMENTIDXEditFieldLabel        matlab.ui.control.Label
        PHASEOFFSETEditField            matlab.ui.control.NumericEditField
        PHASEOFFSETEditFieldLabel       matlab.ui.control.Label
        LEVELOFFSETEditField            matlab.ui.control.NumericEditField
        LEVELOFFSETEditFieldLabel       matlab.ui.control.Label
        FREQOFFSETEditField             matlab.ui.control.NumericEditField
        FREQOFFSETEditFieldLabel        matlab.ui.control.Label
        M1CheckBox                      matlab.ui.control.CheckBox
        M2CheckBox                      matlab.ui.control.CheckBox
        M3CheckBox                      matlab.ui.control.CheckBox
        IGNORE_ADWCheckBox              matlab.ui.control.CheckBox
        SEG_INTERRUPTCheckBox           matlab.ui.control.CheckBox
        USE_EXTENSIONCheckBox           matlab.ui.control.CheckBox
        SEGCheckBox                     matlab.ui.control.CheckBox
        SendADWButton                   matlab.ui.control.Button
        ADWStreamLabel                  matlab.ui.control.Label
        IPAddressEditField_2            matlab.ui.control.EditField
        IPAddressEditField_2Label       matlab.ui.control.Label
        ConnectButton_5                 matlab.ui.control.Button
        ConnectStreamingEngineRaspberryPiLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        
        bb_streams = {}; % Description
        bb_stream_IPs = {};
        SMWs = {};
        FSWs = {};
        ZNAs = {};
        tcp_engine = [];
        SMW_AI = [];
        FSW_AI = [];
        trainingFolder = 'C:\Users\dhemecou\Documents\MATLAB\Examples\R2023a\deeplearning_shared\ModulationClassificationWithDeepLearningExample\training_pulse_seq_frames';
        predictionActive = 0;
        adw_streamer = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AddButtonPushed(app, event)
            addr = app.BBIPAddressEditField.Value;
            [len,wid] = size(app.bb_streams);
            app.bb_streams{len+1,1} = len+1;
            app.bb_streams{len+1,2} = app.SMWs{len+1};
            app.bb_streams{len+1,3} = addr;
            app.PDWTable.Data = app.bb_streams;
        end

        % Callback function
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Connect_Message.Text = "";
            addr = app.SMWIPAddressEditField.Value;
            [len,wid] = size(app.SMWs)
            for i = 1:len
                if(strcmp(app.UITable_2.Data{i,2},addr))
                    app.Connect_Message.Text = "Already registered SMW";
                    return;
                end
            end
            app.SMWs{len+1,1} = tcpclient(addr,5025, "Timeout",75)
            app.SMWs{len+1,2} = parse_options(app.SMWs{len+1,1})
%             app.SMWs{len+1} = addr;
            app.UITable_2.Data{len+1,1} = len+1;
            app.UITable_2.Data{len+1,2} = addr;
            app.UITable_2.Data{len+1,3} = app.SMWs{len+1,2};
            if(app.SMWs{len+1,2} < 3)
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
            elseif(app.SMWs{len+1,2} == 4)
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
                app.UITable_2.ColumnFormat{4} = {'Standard - 2 basebands','Advanced - 4 basebands'};
            elseif(app.SMWs{len+1,2} == 6)
                app.SMWs{len+1,2}
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
                app.UITable_2.ColumnFormat{4} = {'Standard - 2 basebands','Advanced - 6 basebands'};
            end
            
        end

        % Button pushed function: InitBasebandsButton
        function InitBasebandsButtonPushed(app, event)
            subnet = strcat(num2str(app.SubnetEditField0.Value),".",num2str(app.SubnetEditField1.Value),".",num2str(app.SubnetEditField2.Value),".");
            table = assign_bbs(app.SMWs,subnet)
            for i = 1:length(table)
              app.BB_Table.Data{i,1} = table{i}{1}
              app.BB_Table.Data{i,2} = table{i}{2};
              app.BB_Table.Data{i,3} = sprintf('%s',table{i}{3});
              app.BB_Table.Data{i,4} = sprintf('%s',table{i}{4});
              app.bb_stream_IPs{i} = app.BB_Table.Data{i,3};
            end
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            if(isempty(app.PDWTable.Data))
                app.PDWTable.Data = {};
            end
            app.PDWTable.Data(end+1,:) = {0, 1,'PDW','UNMOD', 0, 0, 0, 20, 5, 5,'LIN',1,'N/A','N/A',1,0,0,'N/A','N/A','N/A','N/A'};
            length(app.bb_stream_IPs)
            app.PDWTable.ColumnFormat{2} = cellstr(string(1:length(app.bb_stream_IPs)));
            app.PDWTable.ColumnFormat{3} = {'PDW', 'ARB','TCDW'};
            app.PDWTable.ColumnFormat{4} = {'UNMOD', ['L' ...
                'CHIRP'],'TCHIRP','BARKER'};
            app.PDWTable.ColumnFormat{11} = {'LIN','COS'};
            app.PDWTable.ColumnFormat{12} = {'ON','OFF'};
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.SMWs{indices(1),3} = app.UITable_2.Data{indices(1),indices(2)};
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            align_smw(app.SMWs{1,1},app.AlignmentFilenameEditField.Value);
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
            filename = sprintf("%s.xlsx",app.PDWFilenameEditField.Value);
            send_pdw_streams(filename,1,0, app.bb_stream_IPs, {app.SMWs{1,1}}, [])
        end

        % Button pushed function: StreamButton_2
        function StreamButton_2Pushed(app, event)
            [len,wid] = size(app.PDWTable.Data);
            dat = (app.PDWTable.ColumnName)';
            dat(end+1:len+1,:) = app.PDWTable.Data;
            send_pdw_streams("XXXX.xlsx",1,0, app.bb_stream_IPs, {app.SMWs{1,1}}, dat);
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            app.tcp_engine = tcpclient(app.IPAddressEditField.Value,8080);
        end

        % Button pushed function: AddEmitterButton
        function AddEmitterButtonPushed(app, event)
            if(isempty(app.EmitterTable.Data))
                app.EmitterTable.Data = {};
            end
            app.EmitterTable.Data(end+1,:) = {'0','300','100','0','0','0','0','0','0','0','0','0','0','0','0','0','0','0'};
        end

        % Button pushed function: SendButton_3
        function SendButton_3Pushed(app, event)
            str = '';
          %  app.EmitterTable.Data(1,1)
            [M,N] = size(app.EmitterTable.Data)
            for i = 1:M
                for j = 1:N
                    if(i == 1 && j ==1)
                        str = app.EmitterTable.Data(i,j);
                    else
                    str = strcat(str,',',app.EmitterTable.Data(i,j));
                    end
                end
            end
            if(app.BasebandDropDown.Value == "ALL")
                bb_indices = "1.2.3.4";
            else
                bb_indices = app.BasebandDropDown.Value;
            end
            str = strcat(bb_indices,',',str);
            str = strcat('EMITTER,',str);
            app.tcp_engine.writeline(str{1,1});
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            str_arr = xlsread(app.AmpFilenameEditField.Value);
            [M,N] = size(str_arr);
            str = num2str(str_arr(1));
            for i = 2:N
                str = strcat(str,',',num2str(str_arr(i)));
            end
            if(app.BasebandDropDown.Value == "ALL")
                bb_indices = "1.2.3.4";
            else
                bb_indices = app.BasebandDropDown.Value;
            end
            str = strcat(bb_indices,',',str);
            str = strcat('AMP_DIR,',str)
            app.tcp_engine.writeline(str)
        end

        % Button pushed function: SendButton_2
        function SendButton_2Pushed(app, event)
            str_arr = xlsread(app.PhaseFilenameEditField.Value);
            [M,N] = size(str_arr);
            str = num2str(str_arr(1));
            for i = 2:N
                str = strcat(str,',',num2str(str_arr(i)));
            end
            if(app.BasebandDropDown.Value == "ALL")
                bb_indices = "1.2.3.4";
            else
                bb_indices = app.BasebandDropDown.Value;
            end
            str = strcat(bb_indices,',',str);
            str = strcat('PHASE_DIR,',str)
            app.tcp_engine.writeline(str)
        end

        % Callback function
        function ClearTableButtonPushed(app, event)
            app.EmitterTable.Data = {};
        end

        % Button pushed function: RemoveEmitterButton_4
        function RemoveEmitterButton_4Pushed(app, event)
            app.EmitterTable.Data = app.EmitterTable.Data(1:end-1,:);
        end

        % Button pushed function: TrainingPathButton
        function TrainingPathButtonPushed(app, event)
            trainingPath = uigetdir;
            app.TrainingFolderEditField.Value = trainingPath;
            app.trainingFolder = app.TrainingFolderEditField.Value;
        end

        % Button pushed function: AddSignalButton
        function AddSignalButtonPushed(app, event)
            % a = sprintf("ftp://%s/",app.SMWIPAddressEditField_2.Value)
            [file,path_out] = uigetfile('*.wv*');
            sig_name = split(file,'.');
            sig_name = sig_name{1};

            paths = [path_out file];
            paths = split(paths,'\');
            paths_out = '/var';
            ind = 0;
            for i = 1:length(paths)
                if(strcmp(paths{i},'user'))
                    ind = 1;
                end
                if(ind>0)
                    paths_out = [paths_out '/' paths{i}];
                end
            end
            
            % file = uigetfile('C:\Users\dhemecou\Documents\ew_streaming\MATLAB_PDW_App\');
            if(isempty(app.SignalFileTable.Data))
                app.SignalFileTable.Data = {};
            end
            app.SignalFileTable.Data(end+1,:) = {sig_name,paths_out,'0'};
        end

        % Button pushed function: CollectTrainingFramesArbFilesButton
        function CollectTrainingFramesArbFilesButtonPushed(app, event)
            app.trainingFolder = app.TrainingFolderEditField.Value;


            %% Delete existing files in the training folder
            % Get a list of all files in the folder with the desired file name pattern.
            myFolder = app.trainingFolder;
            filePattern = fullfile(myFolder, '*.mat'); % Change to whatever pattern you need.
            theFiles = dir(filePattern);
            for k = 1 : length(theFiles)
              baseFileName = theFiles(k).name;
              fullFileName = fullfile(myFolder, baseFileName);
              fprintf(1, 'Now deleting %s\n', fullFileName);
              delete(fullFileName);
            end
            %%%


            training = true;
            tcp = tcpclient("192.168.58.41",5025);
            sig_list = [];
            % emitter = app.SignalFileTable.Data{1,:}
            length(app.SignalFileTable.Data);
            for u = 1:length(app.SignalFileTable.Data)
                newlineItem = app.SignalFileTable.Data{u,:};
                sig_list = [sig_list, string(newlineItem)];
            end
            % % for emitter = ["Emitter_A", "Emitter_B", "Emitter_C","Emitter_D","Emitter_E"]
            % % for emitter = ["Emitter_C","Emitter_D","Emitter_E"]
            for x = 1:length(app.SignalFileTable.Data)
                % SMW = tcpclient("192.168.58.21",5025);
                writeread(app.SMW_AI,sprintf(":SOURce1:BB:ARB:WAV:SEL ""%s""; *OPC?;",app.SignalFileTable.Data{x,2}));
                writeread(app.SMW_AI,sprintf(":SOURce1:BB:FOFFset %d; *OPC?;",str2double(app.SignalFileTable.Data{x,3})));
                CNR = 100;
                pause(1);
                
                for power = -10
                    % for CNR = 20:10:30
                % for power = -30:10:-10
                        writeread(app.SMW_AI,sprintf(":SOURce1:POW:LEV:IMM:AMPL %d; *OPC?;",power));
                        
                        % writeread(SMW,":SOURce1:AWGN:STATe 0; *OPC?;");
                        % writeread(SMW,":SOURce1:AWGN:BWID 100000000; *OPC?;");
                        % writeread(SMW,sprintf(":SOURce1:AWGN:CNR %d; *OPC?;",CNR));

                        writeread(app.SMW_AI,":SOURce1:BB:ARB:TRIG:EXEC; *OPC?;");
                        for i = 1:1
                           label_str = sig_list(x)
                           label = categorical(sig_list(x));
                           if(training == true)
                               filename = fullfile(app.trainingFolder,sprintf('%s_%d_%d_%d', label_str, i, CNR, power));
                           else
                               filename = fullfile(testingFolder,sprintf('%s_%d_%d_%d', label_str, i, CNR, power));
                           end
                           % data_str = writeread(tcp,":TRAC1:DATA? TRACE1;");
                           % data = str2double(split(data_str,","));
                           % frame = data(1:len) + j.*data(len+1:len*2);
                           % % frame = get_training_frame;
                           % save(filename, "frame", "label");

                           frame = get_training_frame(app.FSW_AI);
                           frame = permute(frame,[2 1]);
                           for v = 1:1024:(length(frame)-1024)
                            sub_frame = frame(v:v+1023);
                            % sub_frame(1:3)
                            save(sprintf('%s_%d_%d',filename,i,v),"sub_frame","label");
                           end

                        end
                    % end
                end
            end
            fileID = fopen('signalInfo.txt','w');
            for x = 1:length(app.SignalFileTable.Data)
                fprintf(sprintf('%s %s %s \n',fileID,app.SignalFileTable.Data{x,1},app.SignalFileTable.Data{x,2},app.SignalFileTable.Data{x,3}));
            end
            fclose(fileID);
        end

        % Button pushed function: ConnectButton_3
        function ConnectButton_3Pushed(app, event)
            app.SMW_AI = tcpclient(app.SMWIPAddressEditField_2.Value,5025)
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
            app.FSW_AI = tcpclient(app.FSWIPAddressEditField.Value,5025)
        end

        % Button pushed function: TrainNewNetworkButton
        function TrainNewNetworkButtonPushed(app, event)
            % frameDS = signalDatastore(dataDirectory,'SignalVariableNames',["frame","label"]);
            frameDS = signalDatastore(app.trainingFolder,'SignalVariableNames',["sub_frame","label"]);
            labels = transform(frameDS, @helperModClassReadLabel);
            rxLabels = readall(labels,'UseParallel',parallelComputingLicenseExists());
            modulationTypes = unique(rxLabels);
            
            frameDSTrans = transform(frameDS,@helperModClassIQAsPages);

            percentTrainingSamples = 80;
            percentValidationSamples = 10;
            percentTestSamples = 10;
            splitPercentages = [percentTrainingSamples,percentValidationSamples,percentTestSamples];
            [trainDSTrans,validDSTrans,testDSTrans] = helperModClassSplitData(frameDSTrans,splitPercentages);

            % Gather the training and validation frames into the memory
            trainFramesTall = tall(transform(trainDSTrans, @helperModClassReadFrame));
            rxTrainFrames = gather(trainFramesTall);
            rxTrainFrames = cat(4, rxTrainFrames{:});
            % rxTrainFrames = permute(rxTrainFrames,[2 1 3 4]);
            validFramesTall = tall(transform(validDSTrans, @helperModClassReadFrame));
            rxValidFrames = gather(validFramesTall);
            rxValidFrames = cat(4, rxValidFrames{:});
            % rxValidFrames = permute(rxValidFrames,[2 1 3 4]);
            
            % Gather the training and validation labels into the memory
            trainLabelsTall = tall(transform(trainDSTrans, @helperModClassReadLabel));
            rxTrainLabels = gather(trainLabelsTall);
            % rxTrainLabels = permute(rxTrainLabels,[2 1]);
            validLabelsTall = tall(transform(validDSTrans, @helperModClassReadLabel));
            rxValidLabels = gather(validLabelsTall);
            % rxValidLabels = permute(rxValidLabels,[2 1]);


            sps = 8;                % Samples per symbol
            spf = 1024;             % Samples per frame
            modClassNet = helperModClassCNN(modulationTypes,sps,spf);

            maxEpochs = 12;
            miniBatchSize = 256;
            options = helperModClassTrainingOptions(maxEpochs,miniBatchSize,...
              numel(rxTrainLabels),rxValidFrames,rxValidLabels);

            trainNow = true;
            if trainNow == true
              fprintf('%s - Training the network\n', datestr(toc/86400,'HH:MM:SS'))
              trainedNet = trainNetwork(rxTrainFrames,rxTrainLabels,modClassNet,options);
            else
              load trainedModulationClassificationNetwork
            end

            save(sprintf('%s.mat',app.NewNetworkNameEditField.Value),'trainedNet')
        end

        % Button pushed function: TrainingPathButton_2
        function TrainingPathButton_2Pushed(app, event)
            [file,path_out] = uigetfile('*.mat*');
            sig_name = split(file,'.');
            sig_name = sig_name{1};
            paths = [path_out file];
            app.DeployNetworkEditField.Value = paths;

        end

        % Button pushed function: DeployNetworkButton
        function DeployNetworkButtonPushed(app, event)
            % Create a "File" Target
            hT = dlhdl.Target('Xilinx','Interface','JTAG');
            % load network
            % load('../network.mat');
            % load('Net_emitters_ABCDE_02072024.mat');
            load(app.DeployNetworkEditField.Value);
            % Create a "Workflow" object
            hW = dlhdl.Workflow('Network', trainedNet, 'Bitstream', 'dlproc.mat','Target',hT);
            % compile and deploy
            hW.compile;
            hW.deploy('ProgramBitstream',false,'ProgramNetwork',true);
        end

        % Button pushed function: PredictionActiveButton
        function PredictionActiveButtonPushed(app, event)
            h = aximanager('Xilinx');
            app.predictionActive = 1;
            while(app.predictionActive == 1)
                writememory(h, "A000024C",1);
                writememory(h, "A000034C",0);
                writememory(h, "A000038C",0);
                writememory(h, "A0000224",0);
                writememory(h, "A0000224",1);
                writememory(h, "A0000224",0);
                pause(1)
                done = 0;
                count = 0;
                while(done == 0)
                    done = double((readmemory(h,'A0000220',1)));
                    count = count+1;
                    if(count > 10)
                        display('Error. Cannot Achieve Prediction. Check Deployed Network')
                        app.predictionActive = 0;
                        break;
                    end
                end
                out = (readmemory(h,'00400000',5));
                out_vals = typecast(uint32(out),'single')
                [M,I] = max(out_vals);
                app.PredictedSignalIdxEditField.Value = I;
                app.ConfidenceValueEditField.Value = double(M*100);
            end
        end

        % Button pushed function: PredictionStopButton
        function PredictionStopButtonPushed(app, event)
            app.predictionActive = 0;
        end

        % Button pushed function: ClearTrainingFolderButton
        function ClearTrainingFolderButtonPushed(app, event)
            %% Delete existing files in the training folder
            % Get a list of all files in the folder with the desired file name pattern.
            myFolder = app.trainingFolder;
            filePattern = fullfile(myFolder, '*.mat'); % Change to whatever pattern you need.
            theFiles = dir(filePattern);
            for k = 1 : length(theFiles)
              baseFileName = theFiles(k).name;
              fullFileName = fullfile(myFolder, baseFileName);
              fprintf(1, 'Now deleting %s\n', fullFileName);
              delete(fullFileName);
            end
            %%%
        end

        % Button pushed function: CollectTrainingFramesPDWEngineButton
        function CollectTrainingFramesPDWEngineButtonPushed(app, event)
                       for i = 1:1
                           label_str = app.NewSignalNameEditField.Value;
                           label = categorical(label_str);
                           if(training == true)
                               filename = fullfile(app.trainingFolder,sprintf('%s_%d', label_str, i));
                           else
                               filename = fullfile(testingFolder,sprintf('%s_%d', label_str, i));
                           end
                           % data_str = writeread(tcp,":TRAC1:DATA? TRACE1;");
                           % data = str2double(split(data_str,","));
                           % frame = data(1:len) + j.*data(len+1:len*2);
                           % % frame = get_training_frame;
                           % save(filename, "frame", "label");

                           frame = get_training_frame(app.FSW_AI);
                           frame = permute(frame,[2 1]);
                           for v = 1:1024:(length(frame)-1024)
                            sub_frame = frame(v:v+1023);
                            % sub_frame(1:3)
                            save(sprintf('%s_%d_%d',filename,i,v),"sub_frame","label");
                           end
                        end 
        end

        % Button pushed function: SendADWButton
        function SendADWButtonPushed(app, event)
            adw = strcat(string(app.SEGCheckBox.Value),",",string(app.USE_EXTENSIONCheckBox.Value),",",string(app.SEG_INTERRUPTCheckBox.Value),",",...
                string(app.IGNORE_ADWCheckBox.Value),",",string(app.M3CheckBox.Value),",",string(app.M2CheckBox.Value),",",string(app.M1CheckBox.Value),...
                ",",string(app.FREQOFFSETEditField.Value),",",string(app.LEVELOFFSETEditField.Value),",",string(app.PHASEOFFSETEditField.Value),",",...
                string(app.SEGMENTIDXEditField.Value),",",string(app.BUSRTSRIEditField.Value),",",string(app.BUSRTADDSEGMENTSEditField.Value));
       
            display(adw);
            app.adw_streamer.writeline(adw);
        end

        % Button pushed function: ConnectButton_5
        function ConnectButton_5Pushed(app, event)
            app.adw_streamer = tcpclient(app.IPAddressEditField_2.Value,8080);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SMWBBControlTab = uitab(app.TabGroup);
            app.SMWBBControlTab.Title = 'SMW/BB Control';
            app.UITable_2 = uitable(app.SMWBBControlTab);
            app.UITable_2.ColumnName = {'SMW Num'; 'IP Address'; 'Baseband Streams'; 'Configuration'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false false true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.ConnectButton = uibutton(app.SMWBBControlTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SMWControlInterfaceLabel = uilabel(app.SMWBBControlTab);
            app.SMWControlInterfaceLabel.Text = 'SMW Control Interface:';
            app.Connect_Message = uilabel(app.SMWBBControlTab);
            app.Connect_Message.Text = '';
            app.MessageLabel = uilabel(app.SMWBBControlTab);
            app.MessageLabel.Text = 'Message:';
            app.InitBasebandsButton = uibutton(app.SMWBBControlTab, 'push');
            app.InitBasebandsButton.ButtonPushedFcn = createCallbackFcn(app, @InitBasebandsButtonPushed, true);
            app.InitBasebandsButton.Text = 'Init Basebands';
            app.BB_Table = uitable(app.SMWBBControlTab);
            app.BB_Table.ColumnName = {'SMW Num'; 'BB_Num'; 'IP Address'; 'RF Path'};
            app.BB_Table.RowName = {};
            app.SubnetEditField1 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField1.Value = 168;
            app.SubnetEditField2 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField2.Value = 58;
            app.Label = uilabel(app.SMWBBControlTab);
            app.Label.Text = '.';
            app.Label_2 = uilabel(app.SMWBBControlTab);
            app.Label_2.Text = '.';
            app.Label_3 = uilabel(app.SMWBBControlTab);
            app.Label_3.Text = '.';
            app.BasebandConfigLabel = uilabel(app.SMWBBControlTab);
            app.BasebandConfigLabel.Text = 'Baseband Config:';
            app.SMWIPAddressEditFieldLabel = uilabel(app.SMWBBControlTab);
            app.SMWIPAddressEditFieldLabel.Text = 'SMW IP Address';
            app.SMWIPAddressEditField = uieditfield(app.SMWBBControlTab, 'text');
            app.SMWIPAddressEditField.Value = '192.168.58.25';
            app.BBSubnetLabel = uilabel(app.SMWBBControlTab);
            app.BBSubnetLabel.Text = 'BB Subnet';
            app.SubnetEditField0 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField0.Value = 192;
            app.RFPortAlignmentLabel = uilabel(app.SMWBBControlTab);
            app.RFPortAlignmentLabel.Text = 'RF Port Alignment:';
            app.AlignmentFilenameEditFieldLabel = uilabel(app.SMWBBControlTab);
            app.AlignmentFilenameEditFieldLabel.Text = 'Alignment Filename';
            app.AlignmentFilenameEditField = uieditfield(app.SMWBBControlTab, 'text');
            app.AlignButton = uibutton(app.SMWBBControlTab, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.ExectutePDWsTab = uitab(app.TabGroup);
            app.ExectutePDWsTab.Title = 'Exectute PDWs';
            app.StreamExcelFileLabel = uilabel(app.ExectutePDWsTab);
            app.StreamExcelFileLabel.Text = 'Stream Excel File:';
            app.ProcessButton = uibutton(app.ExectutePDWsTab, 'push');
            app.ProcessButton.Text = 'Process';
            app.RealTimeStreamPDWsLabel = uilabel(app.ExectutePDWsTab);
            app.RealTimeStreamPDWsLabel.Text = 'Real-Time Stream PDWs:';
            app.StreamButton = uibutton(app.ExectutePDWsTab, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            app.PDWFilenameEditFieldLabel = uilabel(app.ExectutePDWsTab);
            app.PDWFilenameEditFieldLabel.Text = 'PDW Filename';
            app.PDWFilenameEditField = uieditfield(app.ExectutePDWsTab, 'text');
            app.StatusLabel = uilabel(app.ExectutePDWsTab);
            app.StatusLabel.Text = 'Status:';
            app.PDWTable = uitable(app.ExectutePDWsTab);
            app.PDWTable.ColumnName = {'TOA (s)'; 'BB Num.'; 'Word Type'; 'MOP'; 'Freq Offset (Hz)'; 'Level Offset (dB)'; 'Phase Offset (deg.)'; 'Pulse Width(us)/Arb Seg Idx.'; 'Rise Time (ns)'; 'Fall Time (ns)'; 'Edge Shape'; 'Repetitions'; 'Bandwidth (MHz)'; 'Chip Rate (MHz)'; 'Marker 1'; 'Marker 2'; 'Marker 3'; 'RF Path'; 'Control Command'; 'Frequency (Hz)'; 'Level (dBm)'};
            app.PDWTable.RowName = {};
            app.PDWTable.ColumnEditable = true;
            app.AddRowButton = uibutton(app.ExectutePDWsTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.StreamButton_2 = uibutton(app.ExectutePDWsTab, 'push');
            app.StreamButton_2.ButtonPushedFcn = createCallbackFcn(app, @StreamButton_2Pushed, true);
            app.StreamButton_2.Text = 'Stream';
            app.EmitterInterfaceTab = uitab(app.TabGroup);
            app.EmitterInterfaceTab.Title = 'Emitter Interface';
            app.ConnectStreamingEngineLabel = uilabel(app.EmitterInterfaceTab);
            app.ConnectStreamingEngineLabel.Text = 'Connect Streaming Engine';
            app.AntennaAmplitudeFileStreamLabel = uilabel(app.EmitterInterfaceTab);
            app.AntennaAmplitudeFileStreamLabel.Text = 'Antenna Amplitude File Stream';
            app.AntennaPhaseFileStreamLabel = uilabel(app.EmitterInterfaceTab);
            app.AntennaPhaseFileStreamLabel.Text = 'Antenna Phase File Stream';
            app.EmitterStreamLabel = uilabel(app.EmitterInterfaceTab);
            app.EmitterStreamLabel.Text = 'Emitter Stream';
            app.IPAddressEditFieldLabel = uilabel(app.EmitterInterfaceTab);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.EmitterInterfaceTab, 'text');
            app.IPAddressEditField.Value = '192.168.58.8';
            app.ConnectButton_2 = uibutton(app.EmitterInterfaceTab, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.FilenameEditFieldLabel = uilabel(app.EmitterInterfaceTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.AmpFilenameEditField = uieditfield(app.EmitterInterfaceTab, 'text');
            app.SendButton = uibutton(app.EmitterInterfaceTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.SendButton_2 = uibutton(app.EmitterInterfaceTab, 'push');
            app.SendButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendButton_2Pushed, true);
            app.SendButton_2.Text = 'Send';
            app.FilenameEditField_2Label = uilabel(app.EmitterInterfaceTab);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.PhaseFilenameEditField = uieditfield(app.EmitterInterfaceTab, 'text');
            app.EmitterTable = uitable(app.EmitterInterfaceTab);
            app.EmitterTable.ColumnName = {'MOP'; 'PRI(us)'; 'PW(us)'; 'Time Offset(us)'; 'Edge Type'; 'Rise TIme (ns)'; 'Fall Time (ns)'; 'Chip Width (us)'; 'Barker Code'; 'Freq Offset (Hz)'; 'Level Offset (dB)'; 'Phase Offset (deg)'; 'Freq Inc (MHz)'; 'Bursted?'; 'CPI (us)'; 'Burst Length (us)'; 'CPI Offset (us)'; 'Direction Index'};
            app.EmitterTable.RowName = {};
            app.EmitterTable.ColumnEditable = true;
            app.SendButton_3 = uibutton(app.EmitterInterfaceTab, 'push');
            app.SendButton_3.ButtonPushedFcn = createCallbackFcn(app, @SendButton_3Pushed, true);
            app.SendButton_3.Text = 'Send';
            app.AddEmitterButton = uibutton(app.EmitterInterfaceTab, 'push');
            app.AddEmitterButton.ButtonPushedFcn = createCallbackFcn(app, @AddEmitterButtonPushed, true);
            app.AddEmitterButton.Text = 'Add Emitter';
            app.RemoveEmitterButton_4 = uibutton(app.EmitterInterfaceTab, 'push');
            app.RemoveEmitterButton_4.ButtonPushedFcn = createCallbackFcn(app, @RemoveEmitterButton_4Pushed, true);
            app.RemoveEmitterButton_4.Text = 'Remove Emitter';
            app.BasebandDropDownLabel = uilabel(app.EmitterInterfaceTab);
            app.BasebandDropDownLabel.Text = 'Baseband';
            app.BasebandDropDown = uidropdown(app.EmitterInterfaceTab);
            app.BasebandDropDown.Items = {'1', '2', '3', '4', 'ALL'};
            app.BasebandDropDown.Value = '1';
            app.AIMLModelTab = uitab(app.TabGroup);
            app.AIMLModelTab.Title = 'AI/ML Model';
            app.SMWIPAddressLabel = uilabel(app.AIMLModelTab);
            app.SMWIPAddressLabel.Text = 'SMW IP Address:';
            app.SMWIPAddressEditField_2 = uieditfield(app.AIMLModelTab, 'text');
            app.SMWIPAddressEditField_2.Value = '192.168.58.21';
            app.FSWIPAddressLabel = uilabel(app.AIMLModelTab);
            app.FSWIPAddressLabel.Text = 'FSW IP Address:';
            app.FSWIPAddressEditField = uieditfield(app.AIMLModelTab, 'text');
            app.FSWIPAddressEditField.Value = '192.168.58.41';
            app.ConnectButton_3 = uibutton(app.AIMLModelTab, 'push');
            app.ConnectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_3Pushed, true);
            app.ConnectButton_3.Text = 'Connect';
            app.ConnectButton_4 = uibutton(app.AIMLModelTab, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Text = 'Connect';
            app.TrainingFolderEditFieldLabel = uilabel(app.AIMLModelTab);
            app.TrainingFolderEditFieldLabel.Text = 'Training Folder:';
            app.TrainingFolderEditField = uieditfield(app.AIMLModelTab, 'text');
            app.TrainingFolderEditField.Value = 'C:\Users\dhemecou\Documents\MATLAB\Examples\R2023a\deeplearning_shared\ModulationClassificationWithDeepLearningExample\training_pulse_seq_frames';
            app.TrainingPathButton = uibutton(app.AIMLModelTab, 'push');
            app.TrainingPathButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingPathButtonPushed, true);
            app.TrainingPathButton.Text = '...';
            app.TrainNewNetworkButton = uibutton(app.AIMLModelTab, 'push');
            app.TrainNewNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNewNetworkButtonPushed, true);
            app.TrainNewNetworkButton.Text = 'Train New Network';
            app.SignalFileTable = uitable(app.AIMLModelTab);
            app.SignalFileTable.ColumnName = {'Signal Name'; 'SMW File'; 'Baseband Offset'};
            app.SignalFileTable.RowName = {};
            app.SignalFileTable.ColumnEditable = true;
            app.AddSignalButton = uibutton(app.AIMLModelTab, 'push');
            app.AddSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AddSignalButtonPushed, true);
            app.AddSignalButton.Text = 'Add Signal';
            app.CollectTrainingFramesArbFilesButton = uibutton(app.AIMLModelTab, 'push');
            app.CollectTrainingFramesArbFilesButton.ButtonPushedFcn = createCallbackFcn(app, @CollectTrainingFramesArbFilesButtonPushed, true);
            app.CollectTrainingFramesArbFilesButton.Text = 'Collect Training Frames - Arb Files';
            app.NewNetworkNameEditFieldLabel = uilabel(app.AIMLModelTab);
            app.NewNetworkNameEditFieldLabel.Text = 'New Network Name:';
            app.NewNetworkNameEditField = uieditfield(app.AIMLModelTab, 'text');
            app.NewNetworkNameEditField.Value = 'trainedNetwork';
            app.DeployNetworkEditFieldLabel = uilabel(app.AIMLModelTab);
            app.DeployNetworkEditFieldLabel.Text = 'Deploy Network:';
            app.DeployNetworkEditField = uieditfield(app.AIMLModelTab, 'text');
            app.DeployNetworkEditField.Value = 'trainedNetwork';
            app.DeployNetworkButton = uibutton(app.AIMLModelTab, 'push');
            app.DeployNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DeployNetworkButtonPushed, true);
            app.DeployNetworkButton.Text = 'Deploy Network';
            app.TrainingPathButton_2 = uibutton(app.AIMLModelTab, 'push');
            app.TrainingPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrainingPathButton_2Pushed, true);
            app.TrainingPathButton_2.Text = '...';
            app.PredictionActiveButton = uibutton(app.AIMLModelTab, 'push');
            app.PredictionActiveButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionActiveButtonPushed, true);
            app.PredictionActiveButton.Text = 'Prediction Active';
            app.PredictedSignalIdxEditFieldLabel = uilabel(app.AIMLModelTab);
            app.PredictedSignalIdxEditFieldLabel.Text = 'Predicted Signal Idx:';
            app.PredictedSignalIdxEditField = uieditfield(app.AIMLModelTab, 'numeric');
            app.ConfidenceValueEditFieldLabel = uilabel(app.AIMLModelTab);
            app.ConfidenceValueEditFieldLabel.Text = 'Confidence Value (%): ';
            app.ConfidenceValueEditField = uieditfield(app.AIMLModelTab, 'numeric');
            app.PredictionStopButton = uibutton(app.AIMLModelTab, 'push');
            app.PredictionStopButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionStopButtonPushed, true);
            app.PredictionStopButton.Text = 'Prediction Stop';
            app.CollectTrainingFramesPDWEngineButton = uibutton(app.AIMLModelTab, 'push');
            app.CollectTrainingFramesPDWEngineButton.ButtonPushedFcn = createCallbackFcn(app, @CollectTrainingFramesPDWEngineButtonPushed, true);
            app.CollectTrainingFramesPDWEngineButton.Text = 'Collect Training Frames - PDW Engine';
            app.NewSignalNameEditFieldLabel = uilabel(app.AIMLModelTab);
            app.NewSignalNameEditFieldLabel.Text = 'New Signal Name';
            app.NewSignalNameEditField = uieditfield(app.AIMLModelTab, 'text');
            app.NewSignalNameEditField.Value = 'Sig1';
            app.ClearTrainingFolderButton = uibutton(app.AIMLModelTab, 'push');
            app.ClearTrainingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTrainingFolderButtonPushed, true);
            app.ClearTrainingFolderButton.Text = 'Clear Training Folder';
            app.ADWStreamingTab = uitab(app.TabGroup);
            app.ADWStreamingTab.Title = 'ADW Streaming';
            app.ConnectStreamingEngineRaspberryPiLabel = uilabel(app.ADWStreamingTab);
            app.ConnectStreamingEngineRaspberryPiLabel.Text = 'Connect Streaming Engine (Raspberry Pi)';
            app.ConnectButton_5 = uibutton(app.ADWStreamingTab, 'push');
            app.ConnectButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_5Pushed, true);
            app.ConnectButton_5.Text = 'Connect';
            app.IPAddressEditField_2Label = uilabel(app.ADWStreamingTab);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditField_2 = uieditfield(app.ADWStreamingTab, 'text');
            app.IPAddressEditField_2.Value = '192.168.1.79';
            app.ADWStreamLabel = uilabel(app.ADWStreamingTab);
            app.ADWStreamLabel.Text = 'ADW Stream';
            app.SendADWButton = uibutton(app.ADWStreamingTab, 'push');
            app.SendADWButton.ButtonPushedFcn = createCallbackFcn(app, @SendADWButtonPushed, true);
            app.SendADWButton.Text = 'Send ADW';
            app.SEGCheckBox = uicheckbox(app.ADWStreamingTab);
            app.SEGCheckBox.Enable = 'off';
            app.SEGCheckBox.Text = 'SEG';
            app.SEGCheckBox.Value = true;
            app.USE_EXTENSIONCheckBox = uicheckbox(app.ADWStreamingTab);
            app.USE_EXTENSIONCheckBox.Text = 'USE_EXTENSION';
            app.USE_EXTENSIONCheckBox.Value = true;
            app.SEG_INTERRUPTCheckBox = uicheckbox(app.ADWStreamingTab);
            app.SEG_INTERRUPTCheckBox.Text = 'SEG_INTERRUPT';
            app.SEG_INTERRUPTCheckBox.Value = true;
            app.IGNORE_ADWCheckBox = uicheckbox(app.ADWStreamingTab);
            app.IGNORE_ADWCheckBox.Text = 'IGNORE_ADW';
            app.M3CheckBox = uicheckbox(app.ADWStreamingTab);
            app.M3CheckBox.Text = 'M3';
            app.M2CheckBox = uicheckbox(app.ADWStreamingTab);
            app.M2CheckBox.Text = 'M2';
            app.M1CheckBox = uicheckbox(app.ADWStreamingTab);
            app.M1CheckBox.Text = 'M1';
            app.M1CheckBox.Value = true;
            app.FREQOFFSETEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.FREQOFFSETEditFieldLabel.Text = 'FREQ OFFSET';
            app.FREQOFFSETEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.LEVELOFFSETEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.LEVELOFFSETEditFieldLabel.Text = 'LEVEL OFFSET';
            app.LEVELOFFSETEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.PHASEOFFSETEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.PHASEOFFSETEditFieldLabel.Text = 'PHASE OFFSET';
            app.PHASEOFFSETEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.SEGMENTIDXEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.SEGMENTIDXEditFieldLabel.Text = 'SEGMENT IDX';
            app.SEGMENTIDXEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.BUSRTSRIEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.BUSRTSRIEditFieldLabel.Text = 'BUSRT SRI';
            app.BUSRTSRIEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.BUSRTADDSEGMENTSEditFieldLabel = uilabel(app.ADWStreamingTab);
            app.BUSRTADDSEGMENTSEditFieldLabel.Text = 'BUSRT ADD SEGMENTS';
            app.BUSRTADDSEGMENTSEditField = uieditfield(app.ADWStreamingTab, 'numeric');
            app.HzLabel = uilabel(app.ADWStreamingTab);
            app.HzLabel.Text = 'Hz';
            app.dBLabel = uilabel(app.ADWStreamingTab);
            app.dBLabel.Text = 'dB';
            app.DegreesLabel = uilabel(app.ADWStreamingTab);
            app.DegreesLabel.Text = 'Degrees';
            app.usLabel = uilabel(app.ADWStreamingTab);
            app.usLabel.Text = 'us';
            app.infiniteLabel = uilabel(app.ADWStreamingTab);
            app.infiniteLabel.Text = '0 = infinite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end